classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StopButton           matlab.ui.control.Button
        TextArea             matlab.ui.control.TextArea
        TextAreaLabel        matlab.ui.control.Label
        ExporttoExcelButton  matlab.ui.control.Button
        TrainButton          matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
          
    app.TextArea.Value = "🔁 Starting Training...";

    app.StopButton.UserData = false;  % Reset stop flag

    for t = 1:10
        if app.StopButton.UserData
            app.TextArea.Value = [app.TextArea.Value; "⛔ Training stopped."];
            return;
        end

        % Simulated data
        throughput = 10 + rand(1, 5) * 5;
        latency = 5 + rand(1, 5) * 2;

        % Plot throughput
        bar(app.UIAxes, 1:5, throughput);
        title(app.UIAxes, 'Throughput per User');
        xlabel(app.UIAxes, 'User ID');
        ylabel(app.UIAxes, 'Throughput (Mbps)');

        % Plot latency
        bar(app.UIAxes2, 1:5, latency);
        title(app.UIAxes2, 'Latency per User');
        xlabel(app.UIAxes2, 'User ID');
        ylabel(app.UIAxes2, 'Latency (ms)');

        % Append to TextArea
        msg = sprintf("🧠 Iteration %d done", t);
        app.TextArea.Value = [app.TextArea.Value; msg];

        pause(1);  % Simulate time delay
    end

    app.TextArea.Value = [app.TextArea.Value; "✅ Training complete."];
       
        end

        % Button pushed function: ExporttoExcelButton
        function ExporttoExcelButtonPushed(app, event)
   % Callback function for Export to Excel button
            
    try
        % Export table data to Excel
        data = app.UITable.Data;
        filename = fullfile(tempdir, 'userData.xlsx');  % Temporary path
        writetable(data, filename);

        % Show success message in TextArea
        msg = "📤 Exported successfully to: " + filename;
        app.TextArea.Value = [app.TextArea.Value; msg];

        % Optional: Open the folder automatically
        if ispc  % For Windows
            winopen(tempdir);
        elseif ismac
            system(['open ', tempdir]);
        else  % Linux
            system(['xdg-open ', tempdir]);
        end

    catch ME
        % Show error in TextArea
        app.TextArea.Value = [app.TextArea.Value; "❌ Export failed: " + ME.message];
    end
        
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
         
    app.StopButton.UserData = true;
    app.TextArea.Value = [app.TextArea.Value; "🛑 Stop button pressed."];
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train Button';
            app.ExporttoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelButton.Text = 'Export to Excel';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end